classdef app_bloque_anillo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        AplicacindeCampoElctricoProducidoporunAnilloLabel  matlab.ui.control.Label
        UIAxes          matlab.ui.control.UIAxes
        PartesButton    matlab.ui.control.Button
        PartesButton_2  matlab.ui.control.Button
        PartesButton_4  matlab.ui.control.Button
        FraccionessobrelasquesecalculaelcampoelctricoLabel  matlab.ui.control.Label
        PartesButton_5  matlab.ui.control.Button
        ResetButton     matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PartesButton_4
        function PartesButton_4Pushed(app, event)

            app.UIAxes.cla;
            r=1; q=1; k=9e9;
            V1=linspace(-2*r,2*r,6);
            V2=linspace(-2*r,2*r,6);
            V3=linspace(-2*r,2*r,6);

            [X,Y,Z]=meshgrid(V1,V2,V3);
            N=25;
            theta=linspace(0,2*pi*(N-1)/N,N);
            Ex=0; Ey=0; Ez=0;
            R=@(x,y,z,w) (sqrt(((x-r*cos(w)).^2)+((y-r*sin(w))).^2+(z.^2))).^3;
            for i = 1:N
                Ex=Ex + (k*q).*(X-r*cos(theta(i)))./(R(X,Y,Z,theta(i)));
                Ey=Ey + (k*q).*(Y-r*sin(theta(i)))./(R(X,Y,Z,theta(i)));
                Ez=Ez + (k*q).*(Z)./(R(X,Y,Z,theta(i)));
                
            end
         
            quiver3(app.UIAxes,X,Y,Z,Ex,Ey,Ez)
            hold(app.UIAxes);
            plot(app.UIAxes,r.*cos(theta),r.*sin(theta))         
     
        end

        % Button pushed function: PartesButton_2
        function PartesButton_2Pushed(app, event)
 
            app.UIAxes.cla;
            r=1; q=1; k=9e9;
            V1=linspace(-2*r,2*r,6);
            V2=linspace(-2*r,2*r,6);
            V3=linspace(-2*r,2*r,6);
            [X,Y,Z]=meshgrid(V1,V2,V3);
            N=4;
            theta=linspace(0,2*pi*(N-1)/N,N);
            Dx=0; Dy=0; Dz=0;
            R=@(x,y,z,w) (sqrt(((x-r*cos(w)).^2)+((y-r*sin(w))).^2+(z.^2))).^3;
            for i = 1:N
                Dx=Dx + (k*q).*(X-r*cos(theta(i)))./(R(X,Y,Z,theta(i)));
                Dy=Dy + (k*q).*(Y-r*sin(theta(i)))./(R(X,Y,Z,theta(i)));
                Dz=Dz + (k*q).*(Z)./(R(X,Y,Z,theta(i)));
                
            end
            

            quiver3(app.UIAxes,X,Y,Z,Dx,Dy,Dz)
            hold(app.UIAxes);
            plot(app.UIAxes,r.*cos(theta),r.*sin(theta)) 
        end

        % Button pushed function: PartesButton
        function PartesButtonPushed(app, event)

            r=1; q=1; k=9e9;
            V1=linspace(-2*r,2*r,6);
            V2=linspace(-2*r,2*r,6);
            V3=linspace(-2*r,2*r,6);

            [X,Y,Z]=meshgrid(V1,V2,V3);
            N=2;
            theta=linspace(0,2*pi*(N-1)/N,N);
            Ex=0; Ey=0; Ez=0;
            R=@(x,y,z,w) (sqrt(((x-r*cos(w)).^2)+((y-r*sin(w))).^2+(z.^2))).^3;
            for i = 1:N
                Ex=Ex + (k*q).*(X-r*cos(theta(i)))./(R(X,Y,Z,theta(i)));
                Ey=Ey + (k*q).*(Y-r*sin(theta(i)))./(R(X,Y,Z,theta(i)));
                Ez=Ez + (k*q).*(Z)./(R(X,Y,Z,theta(i)));
                
            end
            

            quiver3(app.UIAxes,X,Y,Z,Ex,Ey,Ez)
            hold(app.UIAxes);
            plot(app.UIAxes,r.*cos(theta),r.*sin(theta)) 
        end

        % Button pushed function: PartesButton_5
        function PartesButton_5Pushed(app, event)

            app.UIAxes.cla;     
            r=1; q=1; k=9e9;
            V1=linspace(-2*r,2*r,6);
            V2=linspace(-2*r,2*r,6);
            V3=linspace(-2*r,2*r,6);
            [X,Y,Z]=meshgrid(V1,V2,V3);
            N=6;
            theta=linspace(0,2*pi*(N-1)/N,N);
            Ex=0; Ey=0; Ez=0;
            R=@(x,y,z,w) (sqrt(((x-r*cos(w)).^2)+((y-r*sin(w))).^2+(z.^2))).^3;
            for i = 1:N
                Ex=Ex + (k*q).*(X-r*cos(theta(i)))./(R(X,Y,Z,theta(i)));
                Ey=Ey + (k*q).*(Y-r*sin(theta(i)))./(R(X,Y,Z,theta(i)));
                Ez=Ez + (k*q).*(Z)./(R(X,Y,Z,theta(i)));
                
            end
            

            quiver3(app.UIAxes,X,Y,Z,Ex,Ey,Ez)
            hold(app.UIAxes);
            plot(app.UIAxes,r.*cos(theta),r.*sin(theta)) 
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)

            app.UIAxes.cla;
        end

        % Callback function
        function ButtonPushed(app, event)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.AplicacindeCampoElctricoProducidoporunAnilloLabel = uilabel(app.UIFigure);
            app.AplicacindeCampoElctricoProducidoporunAnilloLabel.Text = 'Aplicación de Campo Eléctrico Producido por un Anillo';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Gráfica del campo producido')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PartesButton = uibutton(app.UIFigure, 'push');
            app.PartesButton.ButtonPushedFcn = createCallbackFcn(app, @PartesButtonPushed, true);
            app.PartesButton.Text = '2 Partes';
            app.PartesButton_2 = uibutton(app.UIFigure, 'push');
            app.PartesButton_2.ButtonPushedFcn = createCallbackFcn(app, @PartesButton_2Pushed, true);
            app.PartesButton_2.Text = '4 Partes';
            app.PartesButton_4 = uibutton(app.UIFigure, 'push');
            app.PartesButton_4.ButtonPushedFcn = createCallbackFcn(app, @PartesButton_4Pushed, true);
            app.PartesButton_4.Text = '25 Partes';
            app.FraccionessobrelasquesecalculaelcampoelctricoLabel = uilabel(app.UIFigure);
            app.FraccionessobrelasquesecalculaelcampoelctricoLabel.Text = 'Fracciones sobre las que se calcula el campo eléctrico (Presione 2 veces)';
            app.PartesButton_5 = uibutton(app.UIFigure, 'push');
            app.PartesButton_5.ButtonPushedFcn = createCallbackFcn(app, @PartesButton_5Pushed, true);
            app.PartesButton_5.Text = '6 Partes';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_bloque_anillo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end