classdef app_bloque_final_copia < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        tituloprincipal           matlab.ui.control.Label
        titulo1                   matlab.ui.control.Label
        titulo3                   matlab.ui.control.Label
        AlturaEditFieldLabel      matlab.ui.control.Label
        altura                    matlab.ui.control.NumericEditField
        RadioIntEditFieldLabel    matlab.ui.control.Label
        radioint                  matlab.ui.control.NumericEditField
        RadioExtEditFieldLabel    matlab.ui.control.Label
        radioext                  matlab.ui.control.NumericEditField
        UIAxes                    matlab.ui.control.UIAxes
        titulo2                   matlab.ui.control.Label
        Constante1Label           matlab.ui.control.Label
        constante                 matlab.ui.control.NumericEditField
        start                     matlab.ui.control.Button
        reset                     matlab.ui.control.Button
        constanteslabel           matlab.ui.control.Label
        agua                      matlab.ui.control.Label
        etilenglicol              matlab.ui.control.Label
        aceite                    matlab.ui.control.Label
        Constante2EditFieldLabel  matlab.ui.control.Label
        constantealt              matlab.ui.control.NumericEditField
        CapVacioEditFieldLabel    matlab.ui.control.Label
        CapVacioEditField         matlab.ui.control.NumericEditField
        CapLlenoEditFieldLabel    matlab.ui.control.Label
        CapLlenoEditField         matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: start
        function startButtonPushed(app, event)
            %Establecemos los parámetros permanentes
            k=9e9;
            v=0.69;
            const1=app.constante.Value;%Constante dielectrica del primer liquido
            const2=app.constantealt.Value;%Constante dielectrica del primer liquido
            if const2==0
                err=0;
            else
                err=1;
            end
            r1=app.radioint.Value; %La app tiene que permitir cambiar este, el radio interior
            r2=app.radioext.Value; %La app tiene que permitir cambiar este, el radio exterior
            long=app.altura.Value; %Longitud del capacitor tambien se tiene que pedir
            lg=(log(r2)-log(r1));%Calculamos el factor lg
            disp(app.radioint.Value)         
            %Colocamos los vectores de almacenamiento
            ld=[0];
            la=[0.1];
            y1=[];
            x1=1:1:100;
            x2=1:1:100;
            y2=[]; 
            %Añadimos los valores al vector Y del capacitor llenandose
            for i = (1:1:100)
                y1(i)=(const1*((ld(i)/(2*(k)*(lg)))))+((la(i)/(2*(k)*(lg))));
                ld(i+1)=ld(i)+(long/100);
                la(i+1)=((la(i))-(long/100));
            end
            %Añadimos los valores al vector Y2 del capacitor llenandose 
            for i = (1:1:100)
                y2(i)=err*((const2*((ld(i)/(2*(k)*(lg)))))+((la(i)/(2*(k)*(lg)))));
                ld(i+1)=ld(i)+(long/100);
                la(i+1)=((la(i))-(long/100));
            end
            ax0=app.UIAxes;  %Assign your axes to variable
            hold( ax0, 'on' )
            plot(app.UIAxes,x1,y1,'r')
            plot(app.UIAxes,x2,y2,'k')
            %plot as much as you want
            hold( ax0, 'off' )
            
            app.CapVacioEditField.Value=y1(1);
            app.CapLlenoEditField.Value=y1(100);


        end

        % Button pushed function: reset
        function resetButtonPushed(app, event)
            x=0;
            y=0;
            plot(app.UIAxes,x,y)
            app.CapVacioEditField.Value=0;
            app.CapLlenoEditField.Value=0;
            
 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.tituloprincipal = uilabel(app.UIFigure);
            app.tituloprincipal.Text = 'Simulación de Sensor de Nivel Capacitivo';
            app.titulo1 = uilabel(app.UIFigure);
            app.titulo1.Text = 'Parámetros de forma y liquido a medir';
            app.titulo3 = uilabel(app.UIFigure);
            app.titulo3.Text = 'Forma del sensor';
            app.AlturaEditFieldLabel = uilabel(app.UIFigure);
            app.AlturaEditFieldLabel.Text = 'Altura';
            app.altura = uieditfield(app.UIFigure, 'numeric');
            app.altura.Value = 0.1;
            app.RadioIntEditFieldLabel = uilabel(app.UIFigure);
            app.RadioIntEditFieldLabel.Text = 'Radio Int';
            app.radioint = uieditfield(app.UIFigure, 'numeric');
            app.radioint.Value = 0.01;
            app.RadioExtEditFieldLabel = uilabel(app.UIFigure);
            app.RadioExtEditFieldLabel.Text = 'Radio Ext';
            app.radioext = uieditfield(app.UIFigure, 'numeric');
            app.radioext.Value = 0.02;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Gráfica de capacitancia')
            xlabel(app.UIAxes, 'Porcentaje de llenado (%)')
            ylabel(app.UIAxes, 'Capacitancia (F)')
            app.titulo2 = uilabel(app.UIFigure);
            app.titulo2.Text = 'Constante dieléctrica';
            app.Constante1Label = uilabel(app.UIFigure);
            app.Constante1Label.Text = 'Constante 1';
            app.constante = uieditfield(app.UIFigure, 'numeric');
            app.constante.Value = 37.4;
            app.start = uibutton(app.UIFigure, 'push');
            app.start.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.start.Text = 'Start';
            app.reset = uibutton(app.UIFigure, 'push');
            app.reset.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.reset.Text = 'Reset';
            app.constanteslabel = uilabel(app.UIFigure);
            app.constanteslabel.Text = 'Algunas Constante Dieléctricas Conocidas: ';
            app.agua = uilabel(app.UIFigure);
            app.agua.Text = 'Agua: 79';
            app.etilenglicol = uilabel(app.UIFigure);
            app.etilenglicol.Text = 'Etilenglicol: 37.4';
            app.aceite = uilabel(app.UIFigure);
            app.aceite.Text = 'Aceite de Oliva: 20.5';
            app.Constante2EditFieldLabel = uilabel(app.UIFigure);
            app.Constante2EditFieldLabel.Text = 'Constante 2';
            app.constantealt = uieditfield(app.UIFigure, 'numeric');
            app.CapVacioEditFieldLabel = uilabel(app.UIFigure);
            app.CapVacioEditFieldLabel.Text = 'Cap. Vacio';
            app.CapVacioEditField = uieditfield(app.UIFigure, 'numeric');
            app.CapLlenoEditFieldLabel = uilabel(app.UIFigure);
            app.CapLlenoEditFieldLabel.Text = 'Cap. Lleno';
            app.CapLlenoEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_bloque_final_copia

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end